classdef CouplingMatrixApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ITUCouplingMatrixToolUIFigure  matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        PushTool                       matlab.ui.container.toolbar.PushTool
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TextArea                       matlab.ui.control.TextArea
        TabGroup                       matlab.ui.container.TabGroup
        FrequencyResponseTab           matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout4                    matlab.ui.container.GridLayout
        ClearSpecsButton               matlab.ui.control.Button
        ButtonGroup                    matlab.ui.container.ButtonGroup
        PhaseButton                    matlab.ui.control.RadioButton
        GroupDelayButton               matlab.ui.control.RadioButton
        GridLayout3                    matlab.ui.container.GridLayout
        ClicktoAddSpecsButton          matlab.ui.control.Button
        Switch_2                       matlab.ui.control.Switch
        S21CheckBox                    matlab.ui.control.CheckBox
        S11CheckBox                    matlab.ui.control.CheckBox
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        CouplingMatrixTab              matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        GridLayout6                    matlab.ui.container.GridLayout
        ExportMatrixButton             matlab.ui.control.Button
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        CustomButton                   matlab.ui.control.RadioButton
        FoldedButton                   matlab.ui.control.RadioButton
        TransversalButton              matlab.ui.control.RadioButton
        UITransversal                  matlab.ui.control.Table
        NormalizedCouplingMatrixTableLabel  matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        TimeLabel                      matlab.ui.control.Label
        QuEditField                    matlab.ui.control.NumericEditField
        QuEditFieldLabel               matlab.ui.control.Label
        PlotBySwitch                   matlab.ui.control.Switch
        PlotBySwitchLabel              matlab.ui.control.Label
        Freq1EditField                 matlab.ui.control.NumericEditField
        Freq1EditFieldLabel            matlab.ui.control.Label
        Freq2EditField                 matlab.ui.control.NumericEditField
        Freq2EditFieldLabel            matlab.ui.control.Label
        ResolutionLabel                matlab.ui.control.Label
        Knob                           matlab.ui.control.DiscreteKnob
        EndLabel                       matlab.ui.control.Label
        BeginLabel                     matlab.ui.control.Label
        PlotOptionsLabel               matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        TransmissionZeroListLabel      matlab.ui.control.Label
        ComplexTransmissionZeroLabel   matlab.ui.control.Label
        EndEditField                   matlab.ui.control.NumericEditField
        BeginEditField                 matlab.ui.control.NumericEditField
        ClearAllButton                 matlab.ui.control.Button
        AddTZButton                    matlab.ui.control.Button
        ImagEditField                  matlab.ui.control.NumericEditField
        ImagPartLabel                  matlab.ui.control.Label
        RealEditField                  matlab.ui.control.NumericEditField
        RealPartLabel                  matlab.ui.control.Label
        ReturnLossEditField            matlab.ui.control.NumericEditField
        ReturnLossLabel                matlab.ui.control.Label
        OrderDropDown                  matlab.ui.control.DropDown
        OrderLabel                     matlab.ui.control.Label
        PrototypeParametersLabel       matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        filterOrder (1,1) {mustBePositive} = 4;
        returnLoss = 22;
        tzList = [-3.7431j -1.8051j 1.5699j 6.1910j];

        isNormalized = true;
        centerFreq;
        bandwidth;
        plotByPoly = true;
        Qu;
        Pw;
        Fw;
        Ew;
        Ps;
        Fs;
        Es;
        eps;
        eps_r;
        rootsFw;
        rootsEw;
        rootsFs;
        rootsEs;
        y21Num;
        y22Num;
        yDenum;
        Kinf;
        CM;
        Mtrans;
        Mfold;
        Mcustom;
        s21Norm;
        s11Norm;
        groupDelayNorm;
        freqStart;
        freqStop;
        inbandRange = [];
        inbandLevels = [];
        outbandRange = [];
        outbandLevels = [];
        Property16 % Description

        Timer
        CalculatorApp % Description
    end

    methods (Access = private)

        function populateTZTable(app)

            nPoles = length(app.tzList);
            checkBoxes = repmat({true}, nPoles, 1);
            tzs = app.tzList.';
            tzs = num2cell(tzs);
            t = [checkBoxes, tzs];
            app.UITable.ColumnName = {'Enable','Zero'};
            app.UITable.ColumnEditable = [true,true];
            app.UITable.Data = t;

        end

        function calcPolynomails(app)

            n = app.filterOrder;
            nPoles = length(app.tzList);
            poles = app.tzList;
            rl = app.returnLoss;
            app.Pw = computePw(n,poles);
            if mod(n-nPoles,2)
                app.Ps = poly(poles);   % n-nftz is odd
            else
                app.Ps = (poly(poles))*1j; % for preserving unitary condition
            end

            [app.rootsFw, app.Fw] = computeFw(n,poles);
            app.rootsFs = app.rootsFw*1j;
            app.Fs = poly(app.rootsFs);

            [app.eps,app.eps_r] = computeEps(n,rl,app.Pw,app.Fw,nPoles);

            [app.rootsEw, app.Ew] = computeEw(app.Pw,app.Fw,app.eps,app.eps_r);
            app.rootsEs = app.rootsEw*1j;
            app.Es = poly(app.rootsEs);

        end

        function plotMagByPoly(app)

            numSweep = str2num(app.Knob.Value);
            freqstart = app.freqStart;
            freqstop = app.freqStop;
            inRange = app.inbandRange;
            inLevels = app.inbandLevels;
            outRange = app.outbandRange;
            outLevels = app.outbandLevels;
            if app.isNormalized
                wbegin = app.BeginEditField.Value;
                wend = app.EndEditField.Value;
            else  %denormalized
                wbegin = Freq2Omega(app,freqstart);
                wend = Freq2Omega(app,freqstop);
            end

            wSweep = linspace(wbegin,wend,numSweep);
            freqSweep = linspace(freqstart,freqstop,numSweep);

            for ii = 1:numel(wSweep)
                S21(ii) = polyval(app.Pw,wSweep(ii))/polyval(app.Ew,wSweep(ii))/app.eps;
                S11(ii) = polyval(app.Fw,wSweep(ii))/polyval(app.Ew,wSweep(ii))/app.eps_r;
            end
            app.s21Norm = 20*log10(abs(S21));
            app.s11Norm = 20*log10(abs(S11));
            if app.isNormalized
                if app.S21CheckBox.Value && ~app.S11CheckBox.Value
                    plot(app.UIAxes,wSweep,app.s21Norm)
                elseif app.S11CheckBox.Value && ~app.S21CheckBox.Value
                    plot(app.UIAxes,wSweep,app.s11Norm)
                elseif app.S11CheckBox.Value && app.S21CheckBox.Value
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,wSweep,app.s11Norm)
                    plot(app.UIAxes,wSweep,app.s21Norm)
                    hold(app.UIAxes,"off");
                else
                    cla(app.UIAxes);
                end
                xticks(app.UIAxes,wbegin:1:wend);
            else
                if ~isempty(inRange)
                    for ii=1:length(inRange)
                        if ii==1
                            inRange(ii) = app.centerFreq - (app.bandwidth/2);
                        else
                            inRange(ii) = inRange(ii-1) + inRange(ii);
                        end
                    end
                    hold(app.UIAxes,"on");
                    stairs(app.UIAxes,inRange, [inLevels inLevels(end)], 'LineWidth', 1);
                else
                    cla(app.UIAxes);
                end

                if ~isempty(outRange)
                    for ii=1:size(outRange,1)
                        stairs(app.UIAxes,outRange(ii,:), [outLevels(ii) outLevels(ii)], 'LineWidth',1);
                    end
                end

                if app.S21CheckBox.Value && ~app.S11CheckBox.Value
                    plot(app.UIAxes,freqSweep,app.s21Norm)
                elseif app.S11CheckBox.Value && ~app.S21CheckBox.Value
                    plot(app.UIAxes,freqSweep,app.s11Norm)
                elseif app.S11CheckBox.Value && app.S21CheckBox.Value
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,freqSweep,app.s11Norm)
                    plot(app.UIAxes,freqSweep,app.s21Norm)
                    hold(app.UIAxes,"off");
                else
                    cla(app.UIAxes);
                end
                xticks(app.UIAxes,freqstart:25:freqstop);
            end

            ylim(app.UIAxes,[-100 10]);

        end

        function plotPhaseByPoly(app)

            numSweep = str2num(app.Knob.Value);
            freqstart = app.freqStart;
            freqstop = app.freqStop;
            if app.isNormalized
                wbegin = app.BeginEditField.Value;
                wend = app.EndEditField.Value;
            else  %denormalized
                wbegin = Freq2Omega(app,freqstart);
                wend = Freq2Omega(app,freqstop);
            end

            wSweep = linspace(wbegin,wend,numSweep);
            freqSweep = linspace(freqstart,freqstop,numSweep);

            sigma_Es = real(app.rootsEs); omega_Es = imag(app.rootsEs);
            groupDelay = 0;
            phase = 0;
            for ii = 1:numel(app.rootsEs)
                phase = phase + atand((wSweep-omega_Es(ii))/sigma_Es(ii));
                groupDelay = groupDelay - (sigma_Es(ii) ./ (sigma_Es(ii)^2+(wSweep- omega_Es(ii)).^2));
            end
            if app.isNormalized
                if app.PhaseButton.Value
                    plot(app.UIAxes2,wSweep,phase)
                else
                    plot(app.UIAxes2,wSweep,groupDelay)
                end
                xticks(app.UIAxes2,wbegin:1:wend);
            else
                groupDelay = groupDelay*(1/pi/app.bandwidth/1e6);
                if app.PhaseButton.Value
                    plot(app.UIAxes2,freqSweep,phase)
                else
                    plot(app.UIAxes2,freqSweep,groupDelay)
                end
                xticks(app.UIAxes2,freqstart:25:freqstop);
            end

        end

        function calcN2Matrix(app)

            n = app.filterOrder;
            nPoles = length(app.tzList);
            Es = app.Es; Fs = app.Fs; Ps = app.Ps;
            eps = app.eps; eps_r = app.eps_r;
            [app.y21Num,app.y22Num,app.yDenum,app.Kinf] = computeY(n,nPoles,Es,Fs,Ps,eps,eps_r);
            app.Mtrans = synthesizeMatrix(n,app.y21Num,app.y22Num,app.yDenum,app.Kinf);
            app.Mfold = transversal2Folded(n,app.Mtrans);

        end

        function populateMatrixTables(app)
            topology = app.ButtonGroup_2.SelectedObject.Text;
            if topology == "Transversal"
                app.CM = app.Mtrans;
            elseif topology == "Folded"
                app.CM = app.Mfold;
            else
                app.CM = app.Mcustom;
            end
            labels = ["S", string(1:app.filterOrder), "L"];
            widths = [repmat({"auto"},1,app.filterOrder+2)];
            app.UITransversal.ColumnWidth = widths;
            app.UITransversal.RowName = labels;
            app.UITransversal.ColumnName = labels;
            app.UITransversal.Data = app.CM;
            app.UITransversal.ColumnEditable = false;

        end

        function W = Freq2Omega(app,Freq)

            f0 = app.centerFreq;
            bw = app.bandwidth;
            W = 2*(Freq-f0)/bw;

        end

        function freq = Omega2Freq(app,Omega)

            centerfreq = app.centerFreq;
            bandwi = app.bandwidth;
            syms W f0 bw f
            eqn=W==2*(f-f0)/bw;
            sol = solve(eqn,f);
            f = sol(1);
            freq=vpa(subs(f,[f0 bw W],[centerfreq bandwi Omega]),4);

        end

        function initProperties(app)

            app.bandwidth = app.Freq2EditField.Value - app.Freq1EditField.Value;
            app.centerFreq = (app.Freq1EditField.Value + app.Freq2EditField.Value)/2;
            app.freqStart = double(Omega2Freq(app,app.BeginEditField.Value));
            app.freqStop = double(Omega2Freq(app,app.EndEditField.Value));

        end

        function updateClock(app)

            app.TimeLabel.Text = datestr(now,'dd mmm yy HH:MM');

        end

        function plotMagByMatrix(app)

            numSweep = str2num(app.Knob.Value);
            freqstart = app.freqStart;
            freqstop = app.freqStop;
            inRange = app.inbandRange;
            inLevels = app.inbandLevels;
            outRange = app.outbandRange;
            outLevels = app.outbandLevels;
            M = app.Mtrans;
            [~,n] = size(M);
            
            if app.isNormalized
                sig = zeros(n);
            else
                fractionalBW = app.bandwidth/app.centerFreq;
                Q0 = app.QuEditField.Value;
                sig = (1/Q0/fractionalBW)*eye(n);
            end
            U = eye(n); U(1,1) = 0; U(end,end) = 0;
            R = zeros(n); R(1,1) = 1; R(end,end) = 1;

            if app.isNormalized
                wbegin = app.BeginEditField.Value;
                wend = app.EndEditField.Value;
            else  %denormalized
                wbegin = Freq2Omega(app,freqstart);
                wend = Freq2Omega(app,freqstop);
            end

            wSweep = linspace(wbegin,wend,numSweep);
            freqSweep = linspace(freqstart,freqstop,numSweep);

            for ii = 1:numel(wSweep)
                A = wSweep(ii)*U - 1j*R + (M - 1j*sig);
                A_inv = inv(A);
                S21(ii) = -2*1j*A_inv(end,1);
                S11(ii) = 1+2*1j*A_inv(1,1);
            end
            app.s21Norm = 20*log10(abs(S21));
            app.s11Norm = 20*log10(abs(S11));

            if app.isNormalized
                if app.S21CheckBox.Value && ~app.S11CheckBox.Value
                    plot(app.UIAxes,wSweep,app.s21Norm)
                elseif app.S11CheckBox.Value && ~app.S21CheckBox.Value
                    plot(app.UIAxes,wSweep,app.s11Norm)
                elseif app.S11CheckBox.Value && app.S21CheckBox.Value
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,wSweep,app.s11Norm)
                    plot(app.UIAxes,wSweep,app.s21Norm)
                    hold(app.UIAxes,"off");
                else
                    cla(app.UIAxes);
                end
                xticks(app.UIAxes,wbegin:1:wend);
            else
                if ~isempty(inRange)
                    for ii=1:length(inRange)
                        if ii==1
                            inRange(ii) = app.centerFreq - (app.bandwidth/2);
                        else
                            inRange(ii) = inRange(ii-1) + inRange(ii);
                        end
                    end
                    hold(app.UIAxes,"on");
                    stairs(app.UIAxes,inRange, [inLevels inLevels(end)], 'LineWidth', 1);
                else
                    cla(app.UIAxes);
                end

                if ~isempty(outRange)
                    for ii=1:size(outRange,1)
                        stairs(app.UIAxes,outRange(ii,:), [outLevels(ii) outLevels(ii)], 'LineWidth',1);
                    end
                end

                if app.S21CheckBox.Value && ~app.S11CheckBox.Value
                    plot(app.UIAxes,freqSweep,app.s21Norm)
                elseif app.S11CheckBox.Value && ~app.S21CheckBox.Value
                    plot(app.UIAxes,freqSweep,app.s11Norm)
                elseif app.S11CheckBox.Value && app.S21CheckBox.Value
                    hold(app.UIAxes,"on");
                    plot(app.UIAxes,freqSweep,app.s11Norm)
                    plot(app.UIAxes,freqSweep,app.s21Norm)
                    hold(app.UIAxes,"off");
                else
                    cla(app.UIAxes);
                end
                xticks(app.UIAxes,freqstart:25:freqstop);
            end

            ylim(app.UIAxes,[-100 10]);

        end
    end

    methods (Access = public)

        function setSpecLevels(app,inbandRange,inbandLevels,outbandRange,outbandLevels)

            app.inbandRange = inbandRange;
            app.inbandLevels = inbandLevels;
            app.outbandRange = outbandRange;
            app.outbandLevels = outbandLevels;
            cla(app.UIAxes);
            if app.PlotBySwitch.Value == "Poly"
                plotMagByPoly(app);
            else
                plotMagByMatrix(app);
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            initProperties(app);
            populateTZTable(app);
            calcPolynomails(app);
            plotMagByPoly(app);
            plotPhaseByPoly(app);
            calcN2Matrix(app);
            app.CM = app.Mtrans;
            populateMatrixTables(app);
            app.Freq1EditField.Enable = "off";
            app.Freq2EditField.Enable = "off";
            app.QuEditField.Enable = "off";
            app.ClicktoAddSpecsButton.Enable = "off";
            app.ClearSpecsButton.Enable = "off";
            %app.Timer = timer("ExecutionMode","fixedRate","Period",60,"TimerFcn",@(~,~) updateClock(app));
            %start(app.Timer);

        end

        % Value changed function: OrderDropDown
        function OrderDropDownValueChanged(app, event)

            value = app.OrderDropDown.Value;
            app.filterOrder = str2num(value);
            calcPolynomails(app);
            calcN2Matrix(app);
            populateMatrixTables(app);
            cla(app.UIAxes);
            if app.PlotBySwitch.Value == "Poly"
                plotMagByPoly(app);
            else
                plotMagByMatrix(app);
            end
            
            cla(app.UIAxes2);
            plotPhaseByPoly(app);
            

        end

        % Value changed function: ReturnLossEditField
        function ReturnLossEditFieldValueChanged(app, event)

            value = app.ReturnLossEditField.Value;
            app.returnLoss = value;
            calcPolynomails(app);
            calcN2Matrix(app);
            populateMatrixTables(app);
            cla(app.UIAxes);
            if app.PlotBySwitch.Value == "Poly"
                plotMagByPoly(app);
            else
                plotMagByMatrix(app);
            end
            
            cla(app.UIAxes2);
            plotPhaseByPoly(app);
            

        end

        % Button pushed function: AddTZButton
        function AddTZButtonPushed(app, event)

            realPart = app.RealEditField.Value;
            imagPart = app.ImagEditField.Value;
            tz = complex(realPart,imagPart);
            if ~any(app.tzList == tz) % tz is not found in the list
                app.tzList(end+1) = tz;
                newRow = {true,tz};
                oldData = app.UITable.Data;
                newData = [oldData; newRow];
                app.UITable.Data = newData;
                calcPolynomails(app);
                calcN2Matrix(app);
                populateMatrixTables(app);
                cla(app.UIAxes);
                if app.PlotBySwitch.Value == "Poly"
                    plotMagByPoly(app);
                else
                    plotMagByMatrix(app);
                end
                
                cla(app.UIAxes2);
                plotPhaseByPoly(app);
                
            end

        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)

            cla(app.UIAxes);
            plotMagByPoly(app);
            cla(app.UIAxes2);
            plotPhaseByPoly(app);

        end

        % Value changed function: BeginEditField
        function BeginEditFieldValueChanged(app, event)

            if ~app.isNormalized
                value = app.BeginEditField.Value;
                app.freqStart = value;
            end
            cla(app.UIAxes);
            if app.PlotBySwitch.Value == "Poly"
                plotMagByPoly(app);
            else
                plotMagByMatrix(app);
            end
            
            cla(app.UIAxes2);
            plotPhaseByPoly(app);

        end

        % Value changed function: EndEditField
        function EndEditFieldValueChanged(app, event)

            if ~app.isNormalized
                value = app.EndEditField.Value;
                app.freqStop = value;
            end
            cla(app.UIAxes);
            if app.PlotBySwitch.Value == "Poly"
                plotMagByPoly(app);
            else
                plotMagByMatrix(app);
            end

            cla(app.UIAxes2);
            plotPhaseByPoly(app);

        end

        % Value changed function: S11CheckBox
        function S11CheckBoxValueChanged(app, event)

            cla(app.UIAxes);
            plotMagByPoly(app);

        end

        % Value changed function: S21CheckBox
        function S21CheckBoxValueChanged(app, event)

            cla(app.UIAxes);
            plotMagByPoly(app);

        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)

            indices = event.Indices;
            newData = event.NewData;
            oldData = app.UITable.Data;
            rowToDelete = indices(1);
            dataToBeDeleted = cell2mat(oldData(rowToDelete,2));
            app.tzList(app.tzList==dataToBeDeleted) = [];
            populateTZTable(app);
            calcPolynomails(app);
            calcN2Matrix(app);
            populateMatrixTables(app);
            cla(app.UIAxes);
            if app.PlotBySwitch.Value == "Poly"
                plotMagByPoly(app);
            else
                plotMagByMatrix(app);
            end
            cla(app.UIAxes2);
            plotPhaseByPoly(app);
            

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)

            cla(app.UIAxes2);
            plotPhaseByPoly(app);

        end

        % Button pushed function: ExportMatrixButton
        function ExportMatrixButtonPushed(app, event)

            startingFolder = 'C:\'
            defaultFileName = fullfile(startingFolder, 'coupling_matrix.xlsx');
            [baseFileName, folder] = uiputfile(defaultFileName, 'Specify a file for coupling matrix saving ');
            if baseFileName == 0
                % User clicked the Cancel button.
                return;
            end
            [~, baseFileNameNoExt, ext] = fileparts(baseFileName);
            fullFileName = fullfile(folder, [baseFileNameNoExt, '.xlsx'])
            cm = round(app.CM*1e5)/1e5;
            writematrix(cm, fullFileName);

        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)

            mode = app.Switch_2.Value;
            switch mode
                case 'Norm'

                    app.isNormalized = true;
                    app.Freq1EditField.Enable = "off";
                    app.Freq2EditField.Enable = "off";
                    app.QuEditField.Enable = "off";
                    app.ClicktoAddSpecsButton.Enable = "off";
                    app.ClearSpecsButton.Enable = "off";
                    app.BeginEditField.Value = Freq2Omega(app,app.freqStart);
                    app.EndEditField.Value = Freq2Omega(app,app.freqStop);
                    cla(app.UIAxes);
                    plotMagByPoly(app);
                    cla(app.UIAxes2);
                    plotPhaseByPoly(app);

                case 'Denorm'

                    message = ["Band pass domain design mode is active.",...
                        "Please modify the Freq.1 - Freq.2, Q0 and attenuation specification according to your design requirements"];
                    uialert(app.ITUCouplingMatrixToolUIFigure,message,"Band Pass Filter","Icon","info");
                    app.Freq1EditField.Enable = "on";
                    app.Freq2EditField.Enable = "on";
                    app.QuEditField.Enable = "on";
                    app.ClicktoAddSpecsButton.Enable = "on";
                    app.ClearSpecsButton.Enable = "on";
                    app.BeginEditField.Value = app.freqStart;
                    app.EndEditField.Value = app.freqStop;
                    app.isNormalized = false;
                    cla(app.UIAxes);
                    plotMagByPoly(app);
                    cla(app.UIAxes2);
                    plotPhaseByPoly(app);
            end

        end

        % Value changed function: Freq1EditField
        function Freq1EditFieldValueChanged(app, event)

            freq1 = app.Freq1EditField.Value;
            freq2 = app.Freq2EditField.Value;
            app.centerFreq = (freq1 + freq2)/2;
            app.bandwidth = (freq2 -freq1);
            cla(app.UIAxes);
            if app.PlotBySwitch.Value == "Poly"
                plotMagByPoly(app);
            else
                plotMagByMatrix(app);
            end
            
            cla(app.UIAxes2);
            plotPhaseByPoly(app);

        end

        % Value changed function: Freq2EditField
        function Freq2EditFieldValueChanged(app, event)

            freq1 = app.Freq1EditField.Value;
            freq2 = app.Freq2EditField.Value;
            app.centerFreq = (freq1 + freq2)/2;
            app.bandwidth = (freq2 -freq1);
            cla(app.UIAxes);
            if app.PlotBySwitch.Value == "Poly"
                plotMagByPoly(app);
            else
                plotMagByMatrix(app);
            end
            
            cla(app.UIAxes2);
            plotPhaseByPoly(app);

        end

        % Value changed function: QuEditField
        function QuEditFieldValueChanged(app, event)

            cla(app.UIAxes);
            if app.PlotBySwitch.Value == "Poly"
                plotMagByPoly(app);
            else
                plotMagByMatrix(app);
            end

        end

        % Button pushed function: ClicktoAddSpecsButton
        function ClicktoAddSpecsButtonPushed(app, event)

            cf = app.centerFreq;
            bw = app.bandwidth;
            SpecOptionsWindow(app,cf,bw);
            app.ClicktoAddSpecsButton.Enable ="off";

        end

        % Close request function: ITUCouplingMatrixToolUIFigure
        function ITUCouplingMatrixToolUIFigureCloseRequest(app, event)

            delete(app)
            %stop(app.Timer);
            %delete(app.Timer);

        end

        % Button pushed function: ClearSpecsButton
        function ClearSpecsButtonPushed(app, event)
            app.inbandRange = [];
            app.inbandLevels = [];
            app.outbandRange = [];
            app.outbandLevels = [];
            if app.PlotBySwitch.Value == "Poly"
                plotMagByPoly(app);
            else
                plotMagByMatrix(app);
            end
            
        end

        % Value changed function: PlotBySwitch
        function PlotBySwitchValueChanged(app, event)
            value = app.PlotBySwitch.Value;
            switch value
                case "Poly"
                    cla(app.UIAxes);
                    plotMagByPoly(app);
                case "Matrix"
                    cla(app.UIAxes);
                    plotMagByMatrix(app);
            end

        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            
%             selectedButton = app.ButtonGroup_2.SelectedObject;
%             if selectedButton.Text == "Transversal"
%                 app.CM = app.Mtrans;
%             elseif selectedButton.Text == "Folded"
%                 app.CM = app.Mfold;
%             else
%                 app.CM = app.Mcustom;
%             end
            populateMatrixTables(app);
            
        end

        % Callback function: PushTool
        function PushToolClicked(app, event)
            app.PushTool.Enable ="off";
            app.CalculatorApp = FrequencyConverter(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.ITUCouplingMatrixToolUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {587, 587};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {513, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ITUCouplingMatrixToolUIFigure = uifigure('Visible', 'off');
            app.ITUCouplingMatrixToolUIFigure.Name = 'ITU Coupling Matrix Tool';
            app.ITUCouplingMatrixToolUIFigure.CloseRequestFcn = createCallbackFcn(app, @ITUCouplingMatrixToolUIFigureCloseRequest, true);
            app.ITUCouplingMatrixToolUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Toolbar = uitoolbar(app.ITUCouplingMatrixToolUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'icons', 'keys.png');
            app.PushTool.Separator = 'on';
            app.GridLayout = uigridlayout(app.ITUCouplingMatrixToolUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.FrequencyResponseTab = uitab(app.TabGroup);
            app.FrequencyResponseTab.Title = 'Frequency Response';
            app.GridLayout2 = uigridlayout(app.FrequencyResponseTab);
            app.UIAxes2 = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes2, 'Frequency')
            ylabel(app.UIAxes2, 'Phase(deg)/Group Delay(sec)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout2);
            xlabel(app.UIAxes, 'Frequency')
            ylabel(app.UIAxes, 'S11/S21(dB)')
            zlabel(app.UIAxes, 'Z')
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.S11CheckBox = uicheckbox(app.GridLayout3);
            app.S11CheckBox.ValueChangedFcn = createCallbackFcn(app, @S11CheckBoxValueChanged, true);
            app.S11CheckBox.Text = 'S11';
            app.S11CheckBox.Value = true;
            app.S21CheckBox = uicheckbox(app.GridLayout3);
            app.S21CheckBox.ValueChangedFcn = createCallbackFcn(app, @S21CheckBoxValueChanged, true);
            app.S21CheckBox.Text = 'S21';
            app.S21CheckBox.Value = true;
            app.Switch_2 = uiswitch(app.GridLayout3, 'slider');
            app.Switch_2.Items = {'Norm', 'Denorm'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Norm';
            app.ClicktoAddSpecsButton = uibutton(app.GridLayout3, 'push');
            app.ClicktoAddSpecsButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoAddSpecsButtonPushed, true);
            app.ClicktoAddSpecsButton.Text = 'Click to Add Specs.';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.ButtonGroup = uibuttongroup(app.GridLayout4);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.GroupDelayButton = uiradiobutton(app.ButtonGroup);
            app.GroupDelayButton.Text = 'Group Delay';
            app.GroupDelayButton.Value = true;
            app.PhaseButton = uiradiobutton(app.ButtonGroup);
            app.PhaseButton.Text = 'Phase';
            app.ClearSpecsButton = uibutton(app.GridLayout4, 'push');
            app.ClearSpecsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSpecsButtonPushed, true);
            app.ClearSpecsButton.Text = 'Clear Specs.';
            app.CouplingMatrixTab = uitab(app.TabGroup);
            app.CouplingMatrixTab.Title = 'Coupling Matrix';
            app.GridLayout5 = uigridlayout(app.CouplingMatrixTab);
            app.NormalizedCouplingMatrixTableLabel = uilabel(app.GridLayout5);
            app.NormalizedCouplingMatrixTableLabel.Text = 'Normalized Coupling Matrix Table';
            app.UITransversal = uitable(app.GridLayout5);
            app.UITransversal.ColumnName = '';
            app.UITransversal.RowName = {};
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.ButtonGroup_2 = uibuttongroup(app.GridLayout6);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.TransversalButton = uiradiobutton(app.ButtonGroup_2);
            app.TransversalButton.Text = 'Transversal';
            app.TransversalButton.Value = true;
            app.FoldedButton = uiradiobutton(app.ButtonGroup_2);
            app.FoldedButton.Text = 'Folded';
            app.CustomButton = uiradiobutton(app.ButtonGroup_2);
            app.CustomButton.Text = 'Custom';
            app.ExportMatrixButton = uibutton(app.GridLayout6, 'push');
            app.ExportMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMatrixButtonPushed, true);
            app.ExportMatrixButton.Text = 'Export Matrix';
            app.TextArea = uitextarea(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.PrototypeParametersLabel = uilabel(app.RightPanel);
            app.PrototypeParametersLabel.Text = 'Prototype Parameters';
            app.OrderLabel = uilabel(app.RightPanel);
            app.OrderLabel.Text = 'Order:';
            app.OrderDropDown = uidropdown(app.RightPanel);
            app.OrderDropDown.Items = {'4', '5', '6', '7', '8', '9', '10', '11'};
            app.OrderDropDown.ValueChangedFcn = createCallbackFcn(app, @OrderDropDownValueChanged, true);
            app.OrderDropDown.Tooltip = {'Number of resonator in prototype function. Selectivity improves as the value gets bigger while the overall insertion loss performance degrades. '};
            app.OrderDropDown.Value = '4';
            app.ReturnLossLabel = uilabel(app.RightPanel);
            app.ReturnLossLabel.Text = 'Return Loss:';
            app.ReturnLossEditField = uieditfield(app.RightPanel, 'numeric');
            app.ReturnLossEditField.Limits = [0 Inf];
            app.ReturnLossEditField.ValueDisplayFormat = '%11.5g';
            app.ReturnLossEditField.ValueChangedFcn = createCallbackFcn(app, @ReturnLossEditFieldValueChanged, true);
            app.ReturnLossEditField.Value = 22;
            app.RealPartLabel = uilabel(app.RightPanel);
            app.RealPartLabel.Text = 'Real:';
            app.RealEditField = uieditfield(app.RightPanel, 'numeric');
            app.RealEditField.ValueDisplayFormat = '%11.5g';
            app.ImagPartLabel = uilabel(app.RightPanel);
            app.ImagPartLabel.Text = 'Imag:';
            app.ImagEditField = uieditfield(app.RightPanel, 'numeric');
            app.ImagEditField.ValueDisplayFormat = '%11.5g';
            app.AddTZButton = uibutton(app.RightPanel, 'push');
            app.AddTZButton.ButtonPushedFcn = createCallbackFcn(app, @AddTZButtonPushed, true);
            app.AddTZButton.Text = 'Add T.Z.';
            app.ClearAllButton = uibutton(app.RightPanel, 'push');
            app.ClearAllButton.Text = 'Clear All';
            app.BeginEditField = uieditfield(app.RightPanel, 'numeric');
            app.BeginEditField.ValueChangedFcn = createCallbackFcn(app, @BeginEditFieldValueChanged, true);
            app.BeginEditField.Value = -5;
            app.EndEditField = uieditfield(app.RightPanel, 'numeric');
            app.EndEditField.ValueChangedFcn = createCallbackFcn(app, @EndEditFieldValueChanged, true);
            app.EndEditField.Value = 5;
            app.ComplexTransmissionZeroLabel = uilabel(app.RightPanel);
            app.ComplexTransmissionZeroLabel.Text = 'Complex Transmission Zero';
            app.TransmissionZeroListLabel = uilabel(app.RightPanel);
            app.TransmissionZeroListLabel.Text = 'Transmission Zero List';
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.PlotOptionsLabel = uilabel(app.RightPanel);
            app.PlotOptionsLabel.Text = 'Plot Options';
            app.BeginLabel = uilabel(app.RightPanel);
            app.BeginLabel.Text = 'Begin';
            app.EndLabel = uilabel(app.RightPanel);
            app.EndLabel.Text = 'End';
            app.Knob = uiknob(app.RightPanel, 'discrete');
            app.Knob.Items = {'Low', 'Medium', 'High'};
            app.Knob.ItemsData = {'401', '801', '1201'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = '801';
            app.ResolutionLabel = uilabel(app.RightPanel);
            app.ResolutionLabel.Text = 'Resolution';
            app.Freq2EditFieldLabel = uilabel(app.RightPanel);
            app.Freq2EditFieldLabel.Text = 'Freq. 2:';
            app.Freq2EditField = uieditfield(app.RightPanel, 'numeric');
            app.Freq2EditField.ValueChangedFcn = createCallbackFcn(app, @Freq2EditFieldValueChanged, true);
            app.Freq2EditField.Tooltip = {'Pass-band edge upper frequency in MHz. Must be greater than lower edge frequency. Disabled by default. Enabled in denormalized mode of operation.'};
            app.Freq2EditField.Value = 2050;
            app.Freq1EditFieldLabel = uilabel(app.RightPanel);
            app.Freq1EditFieldLabel.Text = 'Freq. 1:';
            app.Freq1EditField = uieditfield(app.RightPanel, 'numeric');
            app.Freq1EditField.ValueChangedFcn = createCallbackFcn(app, @Freq1EditFieldValueChanged, true);
            app.Freq1EditField.Tooltip = {'Pass-band edge lower frequency in MHz. Disabled by default. Enabled in denormalized mode of operation.'};
            app.Freq1EditField.Value = 1950;
            app.PlotBySwitchLabel = uilabel(app.RightPanel);
            app.PlotBySwitchLabel.Text = 'Plot By';
            app.PlotBySwitch = uiswitch(app.RightPanel, 'slider');
            app.PlotBySwitch.Items = {'Poly', 'Matrix'};
            app.PlotBySwitch.ValueChangedFcn = createCallbackFcn(app, @PlotBySwitchValueChanged, true);
            app.PlotBySwitch.Value = 'Poly';
            app.QuEditFieldLabel = uilabel(app.RightPanel);
            app.QuEditFieldLabel.Text = 'Qu:';
            app.QuEditField = uieditfield(app.RightPanel, 'numeric');
            app.QuEditField.ValueDisplayFormat = '%11.5g';
            app.QuEditField.ValueChangedFcn = createCallbackFcn(app, @QuEditFieldValueChanged, true);
            app.QuEditField.Value = 1000;
            app.TimeLabel = uilabel(app.RightPanel);
            app.TimeLabel.Text = 'TimeLabel';
            % Show the figure after all components are created
            app.ITUCouplingMatrixToolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CouplingMatrixApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ITUCouplingMatrixToolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ITUCouplingMatrixToolUIFigure)
        end
    end
end