classdef SpecOptionsWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        BwLabel                    matlab.ui.control.Label
        F0ILEditField              matlab.ui.control.NumericEditField
        ILF0Label                  matlab.ui.control.Label
        BandILEditField            matlab.ui.control.NumericEditField
        MaxILLabel                 matlab.ui.control.Label
        AddAttButton               matlab.ui.control.Button
        dBEditField                matlab.ui.control.NumericEditField
        dBEditFieldLabel           matlab.ui.control.Label
        Fs2EditField               matlab.ui.control.NumericEditField
        Fs2EditFieldLabel          matlab.ui.control.Label
        Fs1EditField               matlab.ui.control.NumericEditField
        Fs1EditFieldLabel          matlab.ui.control.Label
        StopbandAttenuationsLabel  matlab.ui.control.Label
        OKButton                   matlab.ui.control.Button
        UITable                    matlab.ui.control.Table
        AddVarButton               matlab.ui.control.Button
        dBppEditField              matlab.ui.control.NumericEditField
        dBppLabel                  matlab.ui.control.Label
        EditField_2                matlab.ui.control.NumericEditField
        Label_2                    matlab.ui.control.Label
        EditField                  matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        F0Label                    matlab.ui.control.Label
        InbandILVariationsLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        centerFrequency;
        bandwidth;
        centerIL;
        bandIL;
        variationList = [];
        MainApp
    end
    
    methods (Access = private)
        
        function populateVariationTable(app)
            
            nrows = height(app.variationList);
            checkBoxes = repmat({true}, nrows, 1);
            variations = num2cell(app.variationList);
            app.UITable.Data = [checkBoxes,variations];
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, cf, bw)

            app.centerFrequency = cf;
            app.F0Label.Text = string(cf);
            app.bandwidth = bw;
            app.BwLabel.Text = sprintf('Bw:%d MHz',app.bandwidth);
            app.MainApp = mainapp;
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            lowerStopBand = app.variationList(:,2) < (app.centerFrequency - (app.bandwidth/2))
            upperStopBand = app.variationList(:,1) > (app.centerFrequency + (app.bandwidth/2))
            lowerInBand = app.variationList(:,1) >= (app.centerFrequency - (app.bandwidth/2))
            upperInBand = app.variationList(:,1) <= (app.centerFrequency + (app.bandwidth/2))
            
            aa = app.variationList(lowerInBand & upperInBand, 1:2);
            bb = app.variationList(lowerInBand & upperInBand, 3);

            outbandRange = app.variationList(lowerStopBand | upperStopBand, 1:2);
            outbandLevels = app.variationList(lowerStopBand | upperStopBand, 3);


            %variationFreq = sort([app.variationList(:,1);app.variationList(:,2)]);
            variationFreq = sort([aa(:,1);aa(:,2)]);
            inbandRange = [0; diff(variationFreq)]';
            %variationLevels = (app.variationList(:,3))';
            variationLevels = bb';
            inbandLevels = [-variationLevels, -variationLevels(end-1:-1:1)];
            setSpecLevels(app.MainApp,inbandRange,inbandLevels,outbandRange,-outbandLevels);
            app.MainApp.ClicktoAddSpecsButton.Enable ="on";
            delete(app);
        end

        % Button pushed function: AddVarButton
        function AddVarButtonPushed(app, event)
            if app.bandIL < app.centerIL + app.dBppEditField.Value
                message = ["Total in band variation can't be greater than band I.L limit."];
                uialert(app.UIFigure,message,"IL Variations","Icon","error");
            else if (app.EditField.Value + app.EditField_2.Value) > app.bandwidth
                message = ["Overall in-band variation range can't exceed the bandwidth."];
                uialert(app.UIFigure,message,"IL Variations","Icon","error");
            else
                upperEdgeFreq = app.centerFrequency + app.EditField.Value;
                lowerEdgeFreq = app.centerFrequency - app.EditField_2.Value;
                stairLevel = app.centerIL + app.dBppEditField.Value;
                rowToAdd = [lowerEdgeFreq upperEdgeFreq stairLevel];
                app.variationList(end+1,:) = rowToAdd;
                app.variationList = sortrows(app.variationList);
                populateVariationTable(app);
            end
                
            end
            
        end

        % Value changed function: F0ILEditField
        function F0ILEditFieldValueChanged(app, event)
            value = app.F0ILEditField.Value;
            if value > app.bandIL
                message = ["Center frequency I.L. must be smaller than the band I.L. level"];
                uialert(app.UIFigure,message,"IL Variations", "Icon","error");
            else
                app.centerIL = value;
            end
            
        end

        % Value changed function: BandILEditField
        function BandILEditFieldValueChanged(app, event)
            value = app.BandILEditField.Value;
            if value < app.centerIL
                message = ["Band I.L. must be greater than the center frequency I.L level."];
                uialert(app.UIFigure,message,"IL Variations","Icon","error");
            else
                app.bandIL = value;
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            oldData = app.UITable.Data;
            rowToDelete = indices(1);
            dataToBeDeleted = cell2mat(oldData(rowToDelete,2:end));
            rowIdx = ismember(app.variationList,dataToBeDeleted,"rows");
            app.variationList(rowIdx,:) = [];
            populateVariationTable(app);
            
        end

        % Button pushed function: AddAttButton
        function AddAttButtonPushed(app, event)
            lowerEdgeFreq = app.Fs1EditField.Value;
            upperEdgeFreq = app.Fs2EditField.Value;
            stairLevel = app.dBEditField.Value;
            rowToAdd = [lowerEdgeFreq upperEdgeFreq stairLevel];
            app.variationList(end+1,:) = rowToAdd;
            app.variationList = sortrows(app.variationList);
            populateVariationTable(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InbandILVariationsLabel = uilabel(app.UIFigure);
            app.InbandILVariationsLabel.Text = 'In-band I.L. Variations';
            app.F0Label = uilabel(app.UIFigure);
            app.F0Label.Text = 'F0:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '+';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '-';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.dBppLabel = uilabel(app.UIFigure);
            app.dBppLabel.Text = 'dBpp:';
            app.dBppEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddVarButton = uibutton(app.UIFigure, 'push');
            app.AddVarButton.ButtonPushedFcn = createCallbackFcn(app, @AddVarButtonPushed, true);
            app.AddVarButton.Text = 'Add Var.';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Enable'; 'Start'; 'Stop'; 'Mask Level'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.StopbandAttenuationsLabel = uilabel(app.UIFigure);
            app.StopbandAttenuationsLabel.Text = 'Stop-band Attenuations';
            app.Fs1EditFieldLabel = uilabel(app.UIFigure);
            app.Fs1EditFieldLabel.Text = 'Fs1:';
            app.Fs1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Fs2EditFieldLabel = uilabel(app.UIFigure);
            app.Fs2EditFieldLabel.Text = 'Fs2:';
            app.Fs2EditField = uieditfield(app.UIFigure, 'numeric');
            app.dBEditFieldLabel = uilabel(app.UIFigure);
            app.dBEditFieldLabel.Text = 'dB:';
            app.dBEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddAttButton = uibutton(app.UIFigure, 'push');
            app.AddAttButton.ButtonPushedFcn = createCallbackFcn(app, @AddAttButtonPushed, true);
            app.AddAttButton.Text = 'Add Att.';
            app.MaxILLabel = uilabel(app.UIFigure);
            app.MaxILLabel.Text = 'Band I.L. :';
            app.BandILEditField = uieditfield(app.UIFigure, 'numeric');
            app.BandILEditField.ValueChangedFcn = createCallbackFcn(app, @BandILEditFieldValueChanged, true);
            app.ILF0Label = uilabel(app.UIFigure);
            app.ILF0Label.Text = 'F0. I.L. :';
            app.F0ILEditField = uieditfield(app.UIFigure, 'numeric');
            app.F0ILEditField.ValueChangedFcn = createCallbackFcn(app, @F0ILEditFieldValueChanged, true);
            app.BwLabel = uilabel(app.UIFigure);
            app.BwLabel.Text = 'Bw:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpecOptionsWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end